classdef AulaSound < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PanelExperimento                matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        CapturarTonoButton              matlab.ui.control.Button
        Label_33                        matlab.ui.control.Label
        VolverButton                    matlab.ui.control.Button
        Label_32                        matlab.ui.control.Label
        FrecuenciaPanel                 matlab.ui.container.Panel
        ComenzarButton                  matlab.ui.control.Button
        Image9                          matlab.ui.control.Image
        EscribeaquTextArea_2            matlab.ui.control.TextArea
        EscribeaquTextArea_2Label       matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        LoquecalcularemosLabel          matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Label_29                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Experimento2Label               matlab.ui.control.Label
        ResultadosPanel                 matlab.ui.container.Panel
        VolveralmenButton               matlab.ui.control.Button
        RepetirexperimentButton         matlab.ui.control.Button
        Label_24                        matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        UIAxesTeorico                   matlab.ui.control.UIAxes
        UIAxesMediciones                matlab.ui.control.UIAxes
        MedicionesPanel                 matlab.ui.container.Panel
        VerResultadosButton             matlab.ui.control.Button
        Label_23                        matlab.ui.control.Label
        MedicionesUITable               matlab.ui.control.Table
        RegistrarMedicionButton         matlab.ui.control.Button
        REGISTRODERESULTADOSLabel       matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        EstadoMicrofonoLabel            matlab.ui.control.Label
        ActivarMicrofonoButton          matlab.ui.control.Button
        DistanciaEditField              matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel         matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        CONFIGURACINDELAMEDICINLabel    matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        REGISTRODEMEDICIONESLabel       matlab.ui.control.Label
        PROPAGACIONDELSONIDO2Panel      matlab.ui.container.Panel
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        COMENZARMEDICIONESButton        matlab.ui.control.Button
        CONFIGURACINEXPERIMENTALLabel   matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Label_14                        matlab.ui.control.Label
        EscribeaquTextArea              matlab.ui.control.TextArea
        EscribeaquTextAreaLabel         matlab.ui.control.Label
        FORMULATUHIPTESISLabel          matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        TutorialdeusoPanel              matlab.ui.container.Panel
        ANTERIORButton                  matlab.ui.control.StateButton
        Image5                          matlab.ui.control.Image
        Label_10                        matlab.ui.control.Label
        PASO1CONOCEAULASONORALabel      matlab.ui.control.Label
        SIGUIENTEButton                 matlab.ui.control.StateButton
        SaltartutorialButton            matlab.ui.control.StateButton
        TUTORIALDEUSOLabel              matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BIENVENIDOAAULASONORALabel      matlab.ui.control.Label
        LaboratoriodeexperimentosPanel  matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        ACSTICADELASALAPanel            matlab.ui.container.Panel
        Label_9                         matlab.ui.control.Label
        COMENZAREXPERIMENTO3Button_3    matlab.ui.control.StateButton
        FRECUENCIAYONDASPanel           matlab.ui.container.Panel
        Label_8                         matlab.ui.control.Label
        COMENZAREXPERIMENTO2Button_2    matlab.ui.control.StateButton
        PROPAGACINDELSONIDOPanel        matlab.ui.container.Panel
        COMENZAREXPERIMENTO1Button      matlab.ui.control.StateButton
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        EMPEZARLABORATORIOButton        matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LABORATORIODEACSTICALabel       matlab.ui.control.Label
        LabSoundLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

  
    properties (Access = private)
        PasoTutorial = 1
        GrabadorAudio % Objeto para capturar el sonido del micrófono
    end
    % Callbacks that handle component events
    methods (Access = private)
        function startupFcn(app)
            % Crear los datos iniciales con ceros (o puedes usar celdas vacías)
            numMedicion = (1:5)'; 
            distancia = zeros(5, 1);
            nivelSenal = zeros(5, 1);
            
            app.MedicionesUITable.Data = table(numMedicion, distancia, nivelSenal, ...
                'VariableNames', {'N.° de medición', 'Distancia (cm)', 'Nivel de señal'});
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % Crear una celda con 5 filas y 3 columnas iniciales
            datosIniciales = {
                1, 0, 0;
                2, 0, 0;
                3, 0, 0;
                4, 0, 0;
                5, 0, 0
                };

            % Asignarlas directamente a los datos de la tabla
            app.MedicionesUITable.Data = datosIniciales;
        end

        % Button pushed function: EMPEZARLABORATORIOButton
        function EMPEZARLABORATORIOButtonPushed(app, event)
            app.TutorialdeusoPanel.Visible = 'on';
            
        end

        % Value changed function: SaltartutorialButton
        function SaltartutorialButtonValueChanged(app, event)
            value = app.SaltartutorialButton.Value;
            app.TutorialdeusoPanel.Visible = 'off';
            app.LaboratoriodeexperimentosPanel.Visible = 'on';
        end

        % Value changed function: SIGUIENTEButton
        function SIGUIENTEButtonValueChanged(app, event)
           
            if app.PasoTutorial == 4

                app.TutorialdeusoPanel.Visible = 'off';
                app.LaboratoriodeexperimentosPanel.Visible = 'on';
                return

            end
            app.PasoTutorial = app.PasoTutorial + 1;
            
            if app.PasoTutorial == 2

                app.BIENVENIDOAAULASONORALabel.Visible = 'off';
                app.Label_4.Visible = 'off';

                app.PASO1CONOCEAULASONORALabel.Text = 'PASO 2: FORMULA TU HIPOTESIS';

                app.Label_10.Text = 'Antes de comenzar, plantea una hipotesis. ¿Que crees que ocurrira con el nivel sonoro cuando aumente la distancia entre la fuente de sonido y el microfono?';

            end
            if app.PasoTutorial == 3

                app.PASO1CONOCEAULASONORALabel.Text = 'PASO 3: TOMA DE MEDICIONES';

                app.Label_10.Text = 'Ubica el dispositivo a diferentes distancias de la fuente sonora. Activa el microfono, registra las mediciones y repite cada prueba al menos cinco veces para obtener resultados mas confiables.';

            end
            if app.PasoTutorial == 4

                app.PASO1CONOCEAULASONORALabel.Text = 'PASO 4: ANALIZA TUS RESULTADOS';

                app.Label_10.Text = 'Observa las graficas y los datos obtenidos durante el experimento. Compara tus mediciones con los valores teoricos, identifica posibles diferencias y reflexiona sobre como influye la distancia en la propagacion del sonido.';

                app.SIGUIENTEButton.Text = 'COMENZAR';
                app.SIGUIENTEButton.Visible = 'on';
                app.SaltartutorialButton.Visible = 'off';
                app.ANTERIORButton.Visible = 'on';
            end
        end

        % Value changed function: ANTERIORButton
        function ANTERIORButtonValueChanged(app, event)

               if app.PasoTutorial > 1

                    app.PasoTutorial = app.PasoTutorial - 1;

                    app.SIGUIENTEButton.Visible = 'on';

                    if app.PasoTutorial == 1

                        app.BIENVENIDOAAULASONORALabel.Visible = 'on';
                        app.Label_4.Visible = 'on';

                        app.PASO1CONOCEAULASONORALabel.Text = 'PASO 1: CONOCE AULASONORA';

                        app.Label_10.Text = 'AulaSonora es un laboratorio interactivo que permite estudiar los fenomenos acusticos mediante experiencias practicas. Podras explorar el sonido, realizar mediciones y descubrir como se comporta en diferentes condiciones.';

                    elseif app.PasoTutorial == 2

                        app.PASO1CONOCEAULASONORALabel.Text = 'PASO 2: FORMULA TU HIPOTESIS';

                        app.Label_10.Text = 'Antes de comenzar, plantea una hipotesis. ¿Que crees que ocurrira con el nivel sonoro cuando aumente la distancia entre la fuente de sonido y el microfono?';

                    elseif app.PasoTutorial == 3

                        app.PASO1CONOCEAULASONORALabel.Text = 'PASO 3: TOMA DE MEDICIONES';

                        app.Label_10.Text = 'Ubica el dispositivo a diferentes distancias de la fuente sonora. Activa el microfono, registra las mediciones y repite cada prueba al menos cinco veces para obtener resultados mas confiables.';

                    end

               end
        end

        % Button pushed function: ActivarMicrofonoButton
        function ActivarMicrofonoButtonPushed(app, event)
            % Configurar la frecuencia de muestreo estándar
            Fs = 44100; 
            bits = 16;
            canales = 1;

            % Crear el objeto audiorecorder y guardarlo en una propiedad de la app
            app.GrabadorAudio = audiorecorder(Fs, bits, canales);

            % Actualizar texto para indicar que está listo
            app.EstadoMicrofonoLabel.Text = '● Micrófono activado y listo';
            app.EstadoMicrofonoLabel.FontColor = [0 0.5 0]; % Verde
        end

        % Button pushed function: RegistrarMedicionButton
        function RegistrarMedicionButtonPushed(app, event)
            % Verificar que el micrófono haya sido activado previamente
            if isempty(app.GrabadorAudio)
                uialert(app.UIFigure, 'Primero debes activar el micrófono.', 'Aviso');
                return;
            end

            % 1. Obtener la distancia escrita por el usuario en el campo de texto
            distanciaActual = app.DistanciaEditField.Value;

            % 2. Grabar 1 segundo exacto de audio en tiempo real
            recordblocking(app.GrabadorAudio, 1); 
            audioData = getaudiodata(app.GrabadorAudio);

            % 3. Calcular el nivel de la señal usando el valor RMS (amplitud efectiva)
            nivelSenalActual = rms(audioData); 

            % 4. Obtener los datos actuales de la tabla
            datosTabla = app.MedicionesUITable.Data;

            % Buscar la primera fila donde la distancia esté en 0 (vacía)
            filaVacia = find([datosTabla{:, 2}] == 0, 1);

            if ~isempty(filaVacia)
                % Asignar la distancia y el nivel calculado en la fila correspondiente
                datosTabla{filaVacia, 2} = distanciaActual;
                datosTabla{filaVacia, 3} = nivelSenalActual;

                % Actualizar la tabla visualmente en la app
                app.MedicionesUITable.Data = datosTabla;
            else
                uialert(app.UIFigure, 'Ya se han completado las 5 mediciones de esta tanda.', 'Aviso');
            end 
        end

        % Value changed function: COMENZAREXPERIMENTO1Button
        function COMENZAREXPERIMENTO1ButtonValueChanged(app, event)
            % 1. Ocultar el panel actual de selección de experimentos
            app.LaboratoriodeexperimentosPanel.Visible = 'off';

            % 2. Mostrar el panel de hipótesis o mediciones (reemplaza 'HipotesisPanel' 
            % por el nombre exacto que le diste a tu panel en el Component Browser)
            app.PROPAGACIONDELSONIDO2Panel.Visible = 'on';
        end

        % Button pushed function: COMENZARMEDICIONESButton
        function COMENZARMEDICIONESButtonPushed(app, event)
            % 1. Ocultar el panel anterior (por ejemplo, el de la hipótesis o introducción)
            app.PROPAGACINDELSONIDOPanel.Visible = 'on';

            % 2. Mostrar el panel de mediciones donde está la tabla y el micrófono
            app.MedicionesPanel.Visible = 'on';
        end

        % Button pushed function: VerResultadosButton
        function VerResultadosButtonPushed(app, event)

                % 1. Ocultar el panel actual de mediciones
                app.MedicionesPanel.Visible = 'off';

                % 2. Mostrar el panel de resultados
                app.ResultadosPanel.Visible = 'on';

                % 3. Obtener los datos de la tabla de mediciones
                datosTabla = app.MedicionesUITable.Data;
                distancias = [datosTabla{:, 2}]';
                niveles = [datosTabla{:, 3}]';

                % --- GRÁFICO 1: Solo tus mediciones experimentales ---
                % (Asegúrate de que 'UIAxesMediciones' coincida con el nombre exacto de tu primer gráfico)
                cla(app.UIAxesMediciones);
                plot(app.UIAxesMediciones, distancias, niveles, 'bo-', 'LineWidth', 1.5, 'MarkerSize', 6);
                xlabel(app.UIAxesMediciones, 'Distancia (cm)');
                ylabel(app.UIAxesMediciones, 'Nivel de señal (RMS)');
                title(app.UIAxesMediciones, 'Datos Experimentales');
                grid(app.UIAxesMediciones, 'on');

                % --- GRÁFICO 2: Solo la curva teórica ---
                % (Asegúrate de que 'UIAxesTeorico' coincida con el nombre exacto de tu segundo gráfico)
                cla(app.UIAxesTeorico);
                distanciasTeoricas = linspace(min(distancias), max(distancias), 100);
                constanteReferencia = niveles(1) * distancias(1);
                nivelesTeoricos = constanteReferencia ./ distanciasTeoricas;

                plot(app.UIAxesTeorico, distanciasTeoricas, nivelesTeoricos, 'r-', 'LineWidth', 2);
                xlabel(app.UIAxesTeorico, 'Distancia (cm)');
                ylabel(app.UIAxesTeorico, 'Nivel Teórico Esperado');
                title(app.UIAxesTeorico, 'Modelo Teórico (1/r)');
                grid(app.UIAxesTeorico, 'on');
           
        end

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            % 1. Ocultar el panel de resultados actual
            app.ResultadosPanel.Visible = 'off';

            % 2. Ocultar también el panel de mediciones por si acaso quedó activo
            app.MedicionesPanel.Visible = 'off';

            % 3. Ocultar panel de propagacion del sonido
            app.PROPAGACIONDELSONIDO2Panel.Visible = 'off';

            % 4. Mostrar el menú principal de selección de experimentos
            app.LaboratoriodeexperimentosPanel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Gemini_Generated_Image_deatdfdeatdfdeat.jpg');
            app.LabSoundLabel = uilabel(app.UIFigure);
            app.LabSoundLabel.Text = 'LabSound';
            app.LABORATORIODEACSTICALabel = uilabel(app.UIFigure);
            app.LABORATORIODEACSTICALabel.Text = 'LABORATORIO DE ACÚSTICA';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Descubre el mundo del sonido a través de la experimentación.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Explora fenómenos acústicos, realiza mediciones ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'y compara tus resultados con los modelos teóricos.';
            app.EMPEZARLABORATORIOButton = uibutton(app.UIFigure, 'push');
            app.EMPEZARLABORATORIOButton.ButtonPushedFcn = createCallbackFcn(app, @EMPEZARLABORATORIOButtonPushed, true);
            app.EMPEZARLABORATORIOButton.Text = 'EMPEZAR LABORATORIO';
            app.LaboratoriodeexperimentosPanel = uipanel(app.UIFigure);
            app.LaboratoriodeexperimentosPanel.Title = 'Laboratorio de experimentos';
            app.LaboratoriodeexperimentosPanel.Tag = 'ExperimentosPanel';
            app.Label_5 = uilabel(app.LaboratoriodeexperimentosPanel);
            app.Label_5.Text = 'EXPLORA LOS FENÓMENOS DEL SONIDO';
            app.Label_6 = uilabel(app.LaboratoriodeexperimentosPanel);
            app.Label_6.Text = '- Selecciona el experimento que deseas realizar y descubre cómo se comporta el sonido.';
            app.PROPAGACINDELSONIDOPanel = uipanel(app.LaboratoriodeexperimentosPanel);
            app.PROPAGACINDELSONIDOPanel.Title = 'PROPAGACIÓN DEL SONIDO';
            app.Label_7 = uilabel(app.PROPAGACINDELSONIDOPanel);
            app.Label_7.Text = 'Investiga cómo cambia el nivel sonoro al aumentar la distancia entre la fuente y el micrófono.';
            app.COMENZAREXPERIMENTO1Button = uibutton(app.PROPAGACINDELSONIDOPanel, 'state');
            app.COMENZAREXPERIMENTO1Button.ValueChangedFcn = createCallbackFcn(app, @COMENZAREXPERIMENTO1ButtonValueChanged, true);
            app.COMENZAREXPERIMENTO1Button.Text = 'COMENZAR EXPERIMENTO';
            app.FRECUENCIAYONDASPanel = uipanel(app.LaboratoriodeexperimentosPanel);
            app.FRECUENCIAYONDASPanel.Title = 'FRECUENCIA Y ONDAS';
            app.COMENZAREXPERIMENTO2Button_2 = uibutton(app.FRECUENCIAYONDASPanel, 'state');
            app.COMENZAREXPERIMENTO2Button_2.Text = 'COMENZAR EXPERIMENTO';
            app.Label_8 = uilabel(app.FRECUENCIAYONDASPanel);
            app.Label_8.Text = 'Explora las características de las ondas sonoras y analiza sus frecuencias.';
            app.ACSTICADELASALAPanel = uipanel(app.LaboratoriodeexperimentosPanel);
            app.ACSTICADELASALAPanel.Title = 'ACÚSTICA DE LA SALA';
            app.COMENZAREXPERIMENTO3Button_3 = uibutton(app.ACSTICADELASALAPanel, 'state');
            app.COMENZAREXPERIMENTO3Button_3.Text = 'COMENZAR EXPERIMENTO';
            app.Label_9 = uilabel(app.ACSTICADELASALAPanel);
            app.Label_9.Text = 'Descubre cómo los materiales y el entorno influyen en la propagación del sonido.';
            app.Image2 = uiimage(app.LaboratoriodeexperimentosPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-06 003325.png');
            app.Image3 = uiimage(app.LaboratoriodeexperimentosPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-06 003331.png');
            app.Image4 = uiimage(app.LaboratoriodeexperimentosPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-06 003340.png');
            app.TutorialdeusoPanel = uipanel(app.UIFigure);
            app.TutorialdeusoPanel.Title = 'Tutorial de uso';
            app.TutorialdeusoPanel.Visible = 'off';
            app.BIENVENIDOAAULASONORALabel = uilabel(app.TutorialdeusoPanel);
            app.BIENVENIDOAAULASONORALabel.Text = '¡BIENVENIDO A AULASONORA!';
            app.Label_4 = uilabel(app.TutorialdeusoPanel);
            app.Label_4.Text = 'En este laboratorio aprenderás a explorar el sonido mediante experimentos, mediciones y análisis de resultados';
            app.TUTORIALDEUSOLabel = uilabel(app.TutorialdeusoPanel);
            app.TUTORIALDEUSOLabel.Text = 'TUTORIAL DE USO';
            app.SaltartutorialButton = uibutton(app.TutorialdeusoPanel, 'state');
            app.SaltartutorialButton.ValueChangedFcn = createCallbackFcn(app, @SaltartutorialButtonValueChanged, true);
            app.SaltartutorialButton.Text = 'Saltar tutorial';
            app.SIGUIENTEButton = uibutton(app.TutorialdeusoPanel, 'state');
            app.SIGUIENTEButton.ValueChangedFcn = createCallbackFcn(app, @SIGUIENTEButtonValueChanged, true);
            app.SIGUIENTEButton.Text = 'SIGUIENTE';
            app.PASO1CONOCEAULASONORALabel = uilabel(app.TutorialdeusoPanel);
            app.PASO1CONOCEAULASONORALabel.Text = 'PASO 1: CONOCE AULASONORA';
            app.Label_10 = uilabel(app.TutorialdeusoPanel);
            app.Label_10.Text = 'AulaSonora es un laboratorio interactivo que permite estudiar los fenómenos acústicos mediante experiencias prácticas. Podrás explorar el sonido, realizar mediciones y descubrir cómo se comporta en diferentes condiciones.';
            app.Image5 = uiimage(app.TutorialdeusoPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-06 012134.png');
            app.ANTERIORButton = uibutton(app.TutorialdeusoPanel, 'state');
            app.ANTERIORButton.ValueChangedFcn = createCallbackFcn(app, @ANTERIORButtonValueChanged, true);
            app.ANTERIORButton.Text = 'ANTERIOR';
            app.PROPAGACIONDELSONIDO2Panel = uipanel(app.UIFigure);
            app.PROPAGACIONDELSONIDO2Panel.Title = 'PROPAGACIÓN DEL SONIDO';
            app.PROPAGACIONDELSONIDO2Panel.Visible = 'off';
            app.Label_11 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_11.Text = 'EXPERIMENTO 1 · PROPAGACIÓN DEL SONIDO';
            app.Label_12 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_12.Text = 'Investiga cómo cambia el nivel sonoro al aumentar la distancia entre una fuente sonora y el micrófono.';
            app.FORMULATUHIPTESISLabel = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.FORMULATUHIPTESISLabel.Text = 'FORMULA TU HIPÓTESIS ';
            app.EscribeaquTextAreaLabel = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.EscribeaquTextAreaLabel.Text = 'Escribe aquí ';
            app.EscribeaquTextArea = uitextarea(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_14 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_14.Text = 'Antes de comenzar, reflexiona: ¿qué crees que ocurrirá con el nivel sonoro cuando aumente la distancia respecto a la fuente de sonido?';
            app.Image6 = uiimage(app.PROPAGACIONDELSONIDO2Panel);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-08 131034.png');
            app.CONFIGURACINEXPERIMENTALLabel = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.CONFIGURACINEXPERIMENTALLabel.Text = 'CONFIGURACIÓN EXPERIMENTAL';
            app.COMENZARMEDICIONESButton = uibutton(app.PROPAGACIONDELSONIDO2Panel, 'push');
            app.COMENZARMEDICIONESButton.ButtonPushedFcn = createCallbackFcn(app, @COMENZARMEDICIONESButtonPushed, true);
            app.COMENZARMEDICIONESButton.Text = 'COMENZAR MEDICIONES';
            app.Label_16 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_16.Text = 'Antes de comenzar, prepara el espacio de experimentación y establece las condiciones de medición.';
            app.Label_17 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_17.Text = {'1. Ubica una fuente sonora en un lugar fijo.'; '2. Coloca el dispositivo con el micrófono orientado hacia la fuente.'; '3. Mantén constante el volumen de la fuente sonora.'; '4. Realiza mediciones a diferentes distancias.'; '5. Repite cada medición cinco veces para obtener resultados más confiables.'};
            app.Label_18 = uilabel(app.PROPAGACIONDELSONIDO2Panel);
            app.Label_18.Text = 'Instrucciones del experimentó ';
            app.MedicionesPanel = uipanel(app.UIFigure);
            app.MedicionesPanel.Title = 'MedicionesPanel';
            app.MedicionesPanel.Visible = 'off';
            app.REGISTRODEMEDICIONESLabel = uilabel(app.MedicionesPanel);
            app.REGISTRODEMEDICIONESLabel.Text = 'REGISTRO DE MEDICIONES';
            app.Label_19 = uilabel(app.MedicionesPanel);
            app.Label_19.Text = 'Realiza mediciones del nivel de señal sonora a diferentes distancias de la fuente.';
            app.CONFIGURACINDELAMEDICINLabel = uilabel(app.MedicionesPanel);
            app.CONFIGURACINDELAMEDICINLabel.Text = 'CONFIGURACIÓN DE LA MEDICIÓN';
            app.Label_20 = uilabel(app.MedicionesPanel);
            app.Label_20.Text = 'Distancia entre la fuente sonora y el micrófono (cm):';
            app.Label_21 = uilabel(app.MedicionesPanel);
            app.Label_21.Text = 'Realiza cinco mediciones por cada distancia y mantén constante el volumen de la fuente sonora.';
            app.DistanciaEditFieldLabel = uilabel(app.MedicionesPanel);
            app.DistanciaEditFieldLabel.Text = 'Indica aquí la distancia';
            app.DistanciaEditField = uieditfield(app.MedicionesPanel, 'numeric');
            app.DistanciaEditField.Limits = [10 500];
            app.DistanciaEditField.RoundFractionalValues = 'on';
            app.DistanciaEditField.Value = 50;
            app.ActivarMicrofonoButton = uibutton(app.MedicionesPanel, 'push');
            app.ActivarMicrofonoButton.ButtonPushedFcn = createCallbackFcn(app, @ActivarMicrofonoButtonPushed, true);
            app.ActivarMicrofonoButton.Text = 'ACTIVAR MICRÓFONO';
            app.EstadoMicrofonoLabel = uilabel(app.MedicionesPanel);
            app.EstadoMicrofonoLabel.Text = '● Micrófono desactivado';
            app.Label_22 = uilabel(app.MedicionesPanel);
            app.Label_22.Text = 'Presiona "ACTIVAR MICRÓFONO" y permite el acceso al dispositivo cuando MATLAB lo solicite.';
            app.REGISTRODERESULTADOSLabel = uilabel(app.MedicionesPanel);
            app.REGISTRODERESULTADOSLabel.Text = 'REGISTRO DE RESULTADOS';
            app.RegistrarMedicionButton = uibutton(app.MedicionesPanel, 'push');
            app.RegistrarMedicionButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarMedicionButtonPushed, true);
            app.RegistrarMedicionButton.Text = 'REGISTRAR MEDICIÓN';
            app.MedicionesUITable = uitable(app.MedicionesPanel);
            app.MedicionesUITable.ColumnName = {'N.º de medición'; 'Distancia (cm)'; 'Nivel de señal'};
            app.MedicionesUITable.RowName = {};
            app.Label_23 = uilabel(app.MedicionesPanel);
            app.Label_23.Text = 'Haga click aquí para registrar la medición ';
            app.VerResultadosButton = uibutton(app.MedicionesPanel, 'push');
            app.VerResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @VerResultadosButtonPushed, true);
            app.VerResultadosButton.Text = 'Ver Resultados';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.ResultadosPanel.Visible = 'off';
            app.UIAxesMediciones = uiaxes(app.ResultadosPanel);
            title(app.UIAxesMediciones, 'Title')
            xlabel(app.UIAxesMediciones, 'X')
            ylabel(app.UIAxesMediciones, 'Y')
            zlabel(app.UIAxesMediciones, 'Z')
            app.UIAxesTeorico = uiaxes(app.ResultadosPanel);
            title(app.UIAxesTeorico, 'Title')
            xlabel(app.UIAxesTeorico, 'X')
            ylabel(app.UIAxesTeorico, 'Y')
            zlabel(app.UIAxesTeorico, 'Z')
            app.ResultadosLabel = uilabel(app.ResultadosPanel);
            app.ResultadosLabel.Text = 'Resultados';
            app.Label_24 = uilabel(app.ResultadosPanel);
            app.Label_24.Text = 'Aquí encuentras tus resultados y los teóricos del experimentó ';
            app.RepetirexperimentButton = uibutton(app.ResultadosPanel, 'push');
            app.RepetirexperimentButton.Text = 'Repetir experimentó ';
            app.VolveralmenButton = uibutton(app.ResultadosPanel, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú ';
            app.FrecuenciaPanel = uipanel(app.UIFigure);
            app.FrecuenciaPanel.Title = 'Frecuencia y Ondas';
            app.Experimento2Label = uilabel(app.FrecuenciaPanel);
            app.Experimento2Label.Text = 'Experimento 2 ·  Frecuencia y ondas ';
            app.Label_26 = uilabel(app.FrecuenciaPanel);
            app.Label_26.Text = 'Analiza cómo se comportan las ondas sonoras y su frecuencia fundamental (Hz) mediante la captura de tonos y señales acústicas.';
            app.Label_27 = uilabel(app.FrecuenciaPanel);
            app.Label_27.Text = 'El sonido es una onda que se propaga repitiéndose en ciclos. La forma en que percibimos e identificamos un tono depende de la relación matemática entre dos variables clave.';
            app.Label_28 = uilabel(app.FrecuenciaPanel);
            app.Label_28.Text = {'¿Cómo se comportan las ondas en el tiempo?'; ''};
            app.Label_29 = uilabel(app.FrecuenciaPanel);
            app.Label_29.Text = 'Periodo (T): El tiempo que tarda la onda en completar un ciclo utilizando la relación inversa (T = 1/f).';
            app.Image7 = uiimage(app.FrecuenciaPanel);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-09 221106.png');
            app.Image8 = uiimage(app.FrecuenciaPanel);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-06 003107.png');
            app.Label_30 = uilabel(app.FrecuenciaPanel);
            app.Label_30.Text = 'Frecuencia (f): Los ciclos por segundo medidos en Hertz (Hz) de la señal capturada.';
            app.LoquecalcularemosLabel = uilabel(app.FrecuenciaPanel);
            app.LoquecalcularemosLabel.Text = 'Lo que calcularemos:';
            app.Label_31 = uilabel(app.FrecuenciaPanel);
            app.Label_31.Text = 'Antes de comenzar, reflexiona y escribe: ¿Qué pasará con la frecuencia y el gráfico si emites un sonido más agudo o más grave frente al micrófono?';
            app.EscribeaquTextArea_2Label = uilabel(app.FrecuenciaPanel);
            app.EscribeaquTextArea_2Label.Text = 'Escribe aquí ';
            app.EscribeaquTextArea_2 = uitextarea(app.FrecuenciaPanel);
            app.Image9 = uiimage(app.FrecuenciaPanel);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-11-08 131034.png');
            app.ComenzarButton = uibutton(app.FrecuenciaPanel, 'push');
            app.ComenzarButton.Text = 'Comenzar';
            app.PanelExperimento = uipanel(app.UIFigure);
            app.PanelExperimento.Title = 'PanelExperimento';
            app.Label_32 = uilabel(app.PanelExperimento);
            app.Label_32.Text = 'REGISTRO Y ANÁLISIS DE FRECUENCIA';
            app.VolverButton = uibutton(app.PanelExperimento, 'push');
            app.VolverButton.Text = 'Volver a la teoría ';
            app.Label_33 = uilabel(app.PanelExperimento);
            app.Label_33.Text = 'Instrucciones: Emite un sonido constante frente al micrófono (un silbido, una nota sostenida o un tono fijo). Presiona el botón de captura para registrar la frecuencia y repítelo 5 veces';
            app.CapturarTonoButton = uibutton(app.PanelExperimento, 'push');
            app.CapturarTonoButton.Text = '🎙️ CAPTURAR TONO (MEDIR)';
            app.UITable = uitable(app.PanelExperimento);
            app.UITable.ColumnName = {'N° de repeticiones'; 'Frecuencia (Hz)'; 'Periodo T (ms'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AulaSound

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end